classdef PG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ANALYSISTab                     matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        CALCULATEButton                 matlab.ui.control.Button
        COUNTRYDropDownLabel            matlab.ui.control.Label
        COUNTRYDropDown                 matlab.ui.control.DropDown
        TECHNICALANALYSISLabel          matlab.ui.control.Label
        ECONOMICALANALYSISLabel         matlab.ui.control.Label
        EnergyPerDayKWhEditFieldLabel   matlab.ui.control.Label
        EnergyPerDayKWhEditField        matlab.ui.control.NumericEditField
        AverageLoadKWEditFieldLabel     matlab.ui.control.Label
        AverageLoadKWEditField          matlab.ui.control.NumericEditField
        LoadFactorEditFieldLabel        matlab.ui.control.Label
        LoadFactorEditField             matlab.ui.control.NumericEditField
        AnnualEnergyKWhEditFieldLabel   matlab.ui.control.Label
        AnnualEnergyKWhEditField        matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        MaximumDemandMWEditFieldLabel   matlab.ui.control.Label
        MaximumDemandMWEditField        matlab.ui.control.NumericEditField
        PlantCapacityMWEditFieldLabel   matlab.ui.control.Label
        PlantCapacityMWEditField        matlab.ui.control.NumericEditField
        PlantCapacityFactorEditFieldLabel  matlab.ui.control.Label
        PlantCapacityFactorEditField    matlab.ui.control.NumericEditField
        ConstructionCostRsKWEditFieldLabel  matlab.ui.control.Label
        ConstructionCostRsKWEditField   matlab.ui.control.NumericEditField
        OperatingCostRsKwhEditFieldLabel  matlab.ui.control.Label
        OperatingCostRsKwhEditField     matlab.ui.control.NumericEditField
        MaintenancecostRsEditFieldLabel  matlab.ui.control.Label
        MaintenancecostRsEditField      matlab.ui.control.NumericEditField
        FixedCostRsEditFieldLabel       matlab.ui.control.Label
        FixedCostRsEditField            matlab.ui.control.NumericEditField
        IntrerestandDepreciationLabel   matlab.ui.control.Label
        IntrerestandDepreciationEditField  matlab.ui.control.NumericEditField
        VariableCostRsEditFieldLabel    matlab.ui.control.Label
        VariableCostRsEditField         matlab.ui.control.NumericEditField
        CostPerUnitRsKWhEditFieldLabel  matlab.ui.control.Label
        CostPerUnitRsKWhEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        t % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\India.xlsx");
            app.UITable.Data=app.t;
            image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\india.jpeg');
            imshow(image,'parent',app.UIAxes2);
            
        end

        % Value changed function: COUNTRYDropDown
        function COUNTRYDropDownValueChanged(app, event)
            value = app.COUNTRYDropDown.Value;
            
            switch value
                case 'India'
                    app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\India.xlsx");
                    app.UITable.Data=app.t;
                    image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\india.jpeg');
                    imshow(image,'parent',app.UIAxes2);
                    
                case 'Malaysia'
                    app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\Malaysia.xlsx");
                    app.UITable.Data=app.t;
                    image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\malaysia.jpeg');
                    imshow(image,'parent',app.UIAxes2);
                case 'Phillipines'
                    app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\Phillipines.xlsx");
                    app.UITable.Data=app.t;
                    image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\phillipines.jpeg');
                    imshow(image,'parent',app.UIAxes2);
                case 'Singapore'
                    app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\Singapore.xlsx");
                    app.UITable.Data=app.t;
                    image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\singapore.jpeg');
                    imshow(image,'parent',app.UIAxes2);
                case 'Thailand'
                    app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\Thailand.xlsx");
                    app.UITable.Data=app.t;
                    image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\thailand.jpeg');
                    imshow(image,'parent',app.UIAxes2);
                case 'Vietnam'
                    app.t=readtable("C:\Users\hp\Desktop\7th semester\PG CEP\Vietnam.xlsx");
                    app.UITable.Data=app.t;
                    image=imread('C:\Users\hp\Desktop\7th semester\PG CEP\vietnam.jpeg');
                    imshow(image,'parent',app.UIAxes2);
                
            end
            
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            
            x = table2array(app.t(:,1));
            y = table2array(app.t(:,2));
            plot(app.UIAxes,x,y);
            
            a = sum(y)*1000;
            app.EnergyPerDayKWhEditField.Value = a;
            
            b = (sum(y)*1000)/24;
            app.AverageLoadKWEditField.Value = b;
            
            c = max(y);
            app.MaximumDemandMWEditField.Value = c;
            
            d = b/(c*1000);
            app.LoadFactorEditField.Value = d;
            
            e = b*8760;
            app.AnnualEnergyKWhEditField.Value = e;
            
            plant_cap = 1.25*c;
            app.PlantCapacityMWEditField.Value = plant_cap;
            
            plant_cap_factor = (c/plant_cap);
            app.PlantCapacityFactorEditField.Value = plant_cap_factor;


            const_cost = 25000;
            app.ConstructionCostRsKWEditField.Value = const_cost;
            
            int_dep = 10;
            app.IntrerestandDepreciationEditField.Value = int_dep;
            
            fixed_cost = (const_cost*int_dep*plant_cap*1000)/100;
            app.FixedCostRsEditField.Value = fixed_cost;
            
            op_cost = 10;
            app.OperatingCostRsKwhEditField.Value = op_cost;
            
            main_cost = 500000;
            app.MaintenancecostRsEditField.Value = main_cost;
            
            var_cost = (op_cost*e)+main_cost;
            app.VariableCostRsEditField.Value = var_cost;
            
            
            cost_per_unit = (fixed_cost+var_cost)/e;
            app.CostPerUnitRsKWhEditField.Value = cost_per_unit;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ANALYSISTab = uitab(app.TabGroup);
            app.ANALYSISTab.Title = 'ANALYSIS';
            app.UITable = uitable(app.ANALYSISTab);
            app.UITable.ColumnName = {'TIME'; 'LOAD'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.ANALYSISTab);
            title(app.UIAxes, 'LOAD CURVE')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'LOAD')
            app.CALCULATEButton = uibutton(app.ANALYSISTab, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.COUNTRYDropDownLabel = uilabel(app.ANALYSISTab);
            app.COUNTRYDropDownLabel.Text = 'COUNTRY';
            app.COUNTRYDropDown = uidropdown(app.ANALYSISTab);
            app.COUNTRYDropDown.Items = {'India', 'Malaysia', 'Phillipines', 'Singapore', 'Thailand', 'Vietnam'};
            app.COUNTRYDropDown.ValueChangedFcn = createCallbackFcn(app, @COUNTRYDropDownValueChanged, true);
            app.COUNTRYDropDown.Value = 'India';
            app.TECHNICALANALYSISLabel = uilabel(app.ANALYSISTab);
            app.TECHNICALANALYSISLabel.Text = 'TECHNICAL ANALYSIS';
            app.ECONOMICALANALYSISLabel = uilabel(app.ANALYSISTab);
            app.ECONOMICALANALYSISLabel.Text = 'ECONOMICAL ANALYSIS';
            app.EnergyPerDayKWhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.EnergyPerDayKWhEditFieldLabel.Text = 'Energy Per Day (KWh)';
            app.EnergyPerDayKWhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.AverageLoadKWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.AverageLoadKWEditFieldLabel.Text = 'Average Load (KW)';
            app.AverageLoadKWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.LoadFactorEditFieldLabel = uilabel(app.ANALYSISTab);
            app.LoadFactorEditFieldLabel.Text = 'Load Factor';
            app.LoadFactorEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.AnnualEnergyKWhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.AnnualEnergyKWhEditFieldLabel.Text = 'Annual Energy (KWh)';
            app.AnnualEnergyKWhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.UIAxes2 = uiaxes(app.ANALYSISTab);
            title(app.UIAxes2, 'LOAD CURVE')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.MaximumDemandMWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.MaximumDemandMWEditFieldLabel.Text = 'Maximum Demand (MW)';
            app.MaximumDemandMWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.PlantCapacityMWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.PlantCapacityMWEditFieldLabel.Text = 'Plant Capacity (MW)';
            app.PlantCapacityMWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.PlantCapacityFactorEditFieldLabel = uilabel(app.ANALYSISTab);
            app.PlantCapacityFactorEditFieldLabel.Text = 'Plant Capacity Factor';
            app.PlantCapacityFactorEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.ConstructionCostRsKWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.ConstructionCostRsKWEditFieldLabel.Text = 'Construction Cost (Rs/KW)';
            app.ConstructionCostRsKWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.OperatingCostRsKwhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.OperatingCostRsKwhEditFieldLabel.Text = 'Operating Cost (Rs/Kwh)';
            app.OperatingCostRsKwhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.MaintenancecostRsEditFieldLabel = uilabel(app.ANALYSISTab);
            app.MaintenancecostRsEditFieldLabel.Text = 'Maintenance cost (Rs)';
            app.MaintenancecostRsEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.FixedCostRsEditFieldLabel = uilabel(app.ANALYSISTab);
            app.FixedCostRsEditFieldLabel.Text = 'Fixed Cost (Rs)';
            app.FixedCostRsEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.IntrerestandDepreciationLabel = uilabel(app.ANALYSISTab);
            app.IntrerestandDepreciationLabel.Text = 'Intrerest and Depreciation (%)';
            app.IntrerestandDepreciationEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.VariableCostRsEditFieldLabel = uilabel(app.ANALYSISTab);
            app.VariableCostRsEditFieldLabel.Text = 'Variable Cost (Rs)';
            app.VariableCostRsEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.CostPerUnitRsKWhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.CostPerUnitRsKWhEditFieldLabel.Text = 'Cost Per Unit (Rs/KWh)';
            app.CostPerUnitRsKWhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end